classdef CovidDataVisualisation_Dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ClearSearchboxButton        matlab.ui.control.Button
        SearchboxEditField          matlab.ui.control.EditField
        SearchboxEditFieldLabel     matlab.ui.control.Label
        Hyperlink                   matlab.ui.control.Hyperlink
        FromDatePicker              matlab.ui.control.DatePicker
        EndDateLabel                matlab.ui.control.Label
        ToDatePicker                matlab.ui.control.DatePicker
        StartDateLabel              matlab.ui.control.Label
        SetDefaultButton            matlab.ui.control.Button
        AveragingwindowSlider       matlab.ui.control.Slider
        AveragingwindowSliderLabel  matlab.ui.control.Label
        DataToPlotButtonGroup       matlab.ui.container.ButtonGroup
        BothButton                  matlab.ui.control.RadioButton
        DeathsButton                matlab.ui.control.RadioButton
        CasesButton                 matlab.ui.control.RadioButton
        OptionForPlotButtonGroup    matlab.ui.container.ButtonGroup
        DailyButton                 matlab.ui.control.RadioButton
        CumulativeButton            matlab.ui.control.RadioButton
        StateNamesListBox           matlab.ui.control.ListBox
        StateNamesListBoxLabel      matlab.ui.control.Label
        CountryNameListBox          matlab.ui.control.ListBox
        CountryNameListBoxLabel     matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
         AverageCumulativeCases
         AverageCumulativeDeaths
         AverageDailyDeaths
         AverageDailyCases
         AverageOfDay
         CountryorStatesearch
         CountryNamesList
         CountOfCountry         
         ChangedCountry
         ChangedState
         CountryList
         Country
         Cases
         Dates
         EndDate   
         Global
         GlobalCumulativeCases
         GlobalDailyCases
         GlobalCumulativeDeaths
         GlobalDailyDeaths
         IndexC
         IndexS
         Index          
         StartDate
         ShowCumulative
         ShowDaily
         State
         ShowCases
         ShowBoth
         ShowDeaths        
         
            
    end
    methods (Access = public) 
        
        function  Plotgraph(app) 
            cla(app.UIAxes, 'reset');
            app.ComputeAverage();          
           
              if (app.ShowCumulative&&(app.ShowCases&&~app.ShowBoth))                
                app.Cases=app.AverageCumulativeCases;
                bar(app.UIAxes,app.Dates,app.Cases);
                ylabel(app.UIAxes,'Number of Cumulative Cases');
              elseif (app.ShowCumulative&&(app.ShowDeaths &&~app.ShowBoth))
                app.Cases=app.AverageCumulativeDeaths;
                ylabel(app.UIAxes,'Number of Cumulative Deaths');
                plot(app.UIAxes,app.Dates,app.Cases,"Color",'red','LineWidth',1.5);
              elseif (app.ShowDaily&&(app.ShowCases&&~app.ShowBoth))
                app.Cases=app.AverageDailyCases;
                bar(app.UIAxes,app.Dates,app.Cases);
                ylabel(app.UIAxes,'Number of Daily Cases');
              elseif (app.ShowDaily&&(app.ShowDeaths&&~app.ShowBoth))
                app.Cases=app.AverageDailyDeaths;
                plot(app.UIAxes,app.Dates,app.Cases,"Color",'red','LineWidth',1.5);
                ylabel(app.UIAxes,'Number of Daily Deaths');
              elseif (app.ShowCumulative&& app.ShowBoth) 
                yyaxis(app.UIAxes, 'left');
                app.Cases=app.AverageCumulativeCases;
                bar(app.UIAxes,app.Dates,app.Cases);
                ytl = get(app.UIAxes, 'YTick'); 
                ylabel(app.UIAxes, 'Number of Cumulative Cases');
                hold(app.UIAxes,'on');
                yyaxis(app.UIAxes,'right');
                app.Cases=app.AverageCumulativeDeaths;
                plot(app.UIAxes,app.Dates,app.Cases,'r','LineWidth',2);                
                ylabel(app.UIAxes, 'Number of Cumulative Deaths');
                if (max(app.AverageCumulativeDeaths) < 20)
                    app.UIAxes.YTick = unique( round(app.UIAxes.YTick) );
                    app.UIAxes.YLim(1) = 0;
                else
                    ytr = get(app.UIAxes, 'YTick');             
                    ytrv = linspace(0, max(ytr), numel(ytl));   
                    app.UIAxes.YTick = ytrv;                    
                    app.UIAxes.YTickLabels = round(ytrv);       
                end
                hold(app.UIAxes,'off');
              elseif (app.ShowDaily&& app.ShowBoth)
                yyaxis(app.UIAxes, 'left');
                app.Cases=app.AverageDailyCases;
                bar(app.UIAxes,app.Dates,app.Cases);
                ytl = get(app.UIAxes, 'YTick');
                ylabel(app.UIAxes, 'Number of Daily Cases');
                hold(app.UIAxes,'on');
                yyaxis(app.UIAxes,'right');
                app.Cases=app.AverageDailyDeaths;
                plot(app.UIAxes,app.Dates,app.Cases,'r','LineWidth',1.5);                
                ylabel(app.UIAxes, 'Number of Daily Deaths');
                if (max(app.AverageDailyDeaths) < 20)
                    app.UIAxes.YTick = unique(round(app.UIAxes.YTick));
                    app.UIAxes.YLim(1) = 0;
                else
                    ytr = get(app.UIAxes, 'YTick');             
                    ytrv = linspace(0, max(ytr), numel(ytl));   
                    app.UIAxes.YTick = ytrv;                    
                    app.UIAxes.YTickLabels = round(ytrv);       
                end
                hold(app.UIAxes,'off'); 
              end
            title(app.UIAxes,app.TitleMaking()); 
            title(app.UIAxes, app.TitleMaking(), 'FontSize', 18);
            xlabel(app.UIAxes,'Dates');
            grid(app.UIAxes,"on");
            box(app.UIAxes,"off");
            datetick(app.UIAxes,'x','mmm/yy');
            xlim(app.UIAxes, 'manual');
            xlim(app.UIAxes, [ app.StartDate app.EndDate ]);
            % turn off scientic notations on y axes
            if app.ShowBoth
                app.UIAxes.YAxis(1).Exponent = 0;
                app.UIAxes.YAxis(2).Exponent = 0;
            else
                app.UIAxes.YAxis.Exponent = 0;
            end
            ytickformat(app.UIAxes, '%.0f');
                      
        end
        
        function Generatecountryorstatedata(app)            
             app.CountryNamesList=unique(app.Global(2:end,1));
             app.CountOfCountry=length(app.CountryNamesList);
             app.GlobalCumulativeCases=zeros(1,length(app.Dates));
             app.GlobalCumulativeDeaths=zeros(1,length(app.Dates));
             app.GlobalDailyCases=zeros(1,length(app.Dates));
             app.GlobalDailyDeaths=zeros(1,length(app.Dates));
            for i=1:length(app.CountryNamesList)
              [~,app.Index(i)]=ismember(app.CountryNamesList{i},app.Global(:,1));
              app.CountryList{i}=CovidDataOfCountry(app.Global,app.CountryNamesList{i},app.Index(i));
              app.GlobalCumulativeCases=app.GlobalCumulativeCases+app.CountryList{i}.CumulativeCases;
              app.GlobalDailyCases=app.GlobalDailyCases+app.CountryList{i}.DailyCases;
              app.GlobalCumulativeDeaths=app.GlobalCumulativeDeaths+app.CountryList{i}.CumulativeDeaths;
              app.GlobalDailyDeaths=app.GlobalDailyDeaths+app.CountryList{i}.DailyDeaths;
            end
           
        end
        function title =TitleMaking(app)
            if app.ShowCumulative
                ChartOption = 'Cumulative';
            elseif app.ShowDaily
                ChartOption = 'Daily';
            end
            
            if (app.ShowCases && ~app.ShowDeaths)
                ChartDataType = 'Cases';
            elseif (app.ShowDeaths && ~app.ShowCases)
                ChartDataType = 'Deaths';
            else
                ChartDataType = 'Cases and Deaths';
            end
            
            if isequal(app.ChangedCountry, 'Global')
                ChartName = 'Globally';
            elseif isequal(app.ChangedState, 'All')
                ChartName = [ 'in ', app.ChangedCountry ];
            else
                ChartName = [ 'in ', app.ChangedState ];
            end

            if (app.AverageOfDay > 1)
                ChartDaysMean = [ ' (', num2str(app.AverageOfDay), '-day mean)' ];
            else
                ChartDaysMean = '';
            end

            title = [ ChartOption, ' Number of ', ChartDataType, ' ', ChartName, ChartDaysMean ];               
        end
          
                    
        function  Defaultvalue(app)
            app.CountryNamesList=unique(app.Global(2:end,1));
            app.CountryNameListBox.Items = [ {'Global'}; app.CountryNamesList];           
            app.CountryNameListBox.Value='Global';
            scroll(app.CountryNameListBox, 'top');
            app.ChangedCountry=app.CountryNameListBox.Value;
            app.StateNamesListBox.Items ={'All'};
            app.StateNamesListBox.Value='All';
            app.ChangedState=app.StateNamesListBox.Value;       
            app.BothButton.Value=0;
            app.DeathsButton.Value=0;
            app.CasesButton.Value=1;
            app.DailyButton.Value=0;
            app.SearchboxEditField.FontColor = 'black';
            app.SearchboxEditField.BackgroundColor = 'white';
            app.CumulativeButton.Value=1;
            app.ShowCumulative=app.CumulativeButton.Value;
            app.ShowDaily=app.DailyButton.Value;
            app.ShowCases=app.CasesButton.Value||app.BothButton.Value;
            app.ShowBoth=app.BothButton.Value;
            app.ShowDeaths=app.DeathsButton.Value||app.BothButton.Value;
            app.AveragingwindowSlider.Value=1;
            app.AverageOfDay=app.AveragingwindowSlider.Value;
            app.Dates=datenum(app.Global(1,3:end),'mm/dd/yy');
            app.StartDate=app.Dates(1);
            app.EndDate=app.Dates(end);
            app.FromDatePicker.Limits = [ datetime(app.Dates(1), 'ConvertFrom', 'datenum'), ...
                                          datetime(app.Dates(end), 'ConvertFrom', 'datenum') ];
            app.ToDatePicker.Limits = app.FromDatePicker.Limits;
            app.ToDatePicker.Value=datetime(app.EndDate,'convertfrom','datenum');
            app.FromDatePicker.Value=datetime(app.StartDate,'convertfrom','datenum');
        end
        
        function ComputeAverage(app)
          if isequal(app.ChangedCountry,'Global')&& isequal(app.ChangedState,'All')
            if app.AverageOfDay<=1
              app.AverageCumulativeCases=app.GlobalCumulativeCases;
              app.AverageCumulativeDeaths=app.GlobalCumulativeDeaths;
              app.AverageDailyDeaths=app.GlobalDailyDeaths;
              app.AverageDailyCases=app.GlobalDailyCases;
            else                
              app.AverageCumulativeCases=movmean(app.GlobalCumulativeCases,app.AverageOfDay);
              app.AverageCumulativeDeaths=movmean(app.GlobalCumulativeDeaths,app.AverageOfDay);
              app.AverageDailyDeaths=movmean(app.GlobalDailyDeaths,app.AverageOfDay);
              app.AverageDailyCases=movmean(app.GlobalDailyCases,app.AverageOfDay);
            end
          elseif ~isequal(app.ChangedCountry,'Global')
            [~,app.IndexC]=ismember(app.ChangedCountry,app.Global(:,1));
            app.Country=CovidDataOfCountry(app.Global,app.ChangedCountry,app.IndexC);
           if isequal(app.ChangedState,'All')
             if app.AverageOfDay<=1
              app.AverageCumulativeCases=app.Country.CumulativeCases;
              app.AverageCumulativeDeaths=app.Country.CumulativeDeaths;
              app.AverageDailyDeaths=app.Country.DailyDeaths;
              app.AverageDailyCases=app.Country.DailyCases;
             else                
              app.AverageCumulativeCases=movmean(app.Country.CumulativeCases,app.AverageOfDay);
              app.AverageCumulativeDeaths=movmean(app.Country.CumulativeDeaths,app.AverageOfDay);
              app.AverageDailyDeaths=movmean(app.Country.DailyDeaths,app.AverageOfDay);
              app.AverageDailyCases=movmean(app.Country.DailyCases,app.AverageOfDay);
             end
           elseif ~isequal(app.ChangedState,'All')
              [~,app.IndexS]=ismember(app.ChangedState,app.Global(:,2));
              app.State=CovidDataOfState(app.Global,app.ChangedState,app.IndexS);
             if app.AverageOfDay<=1
              app.AverageCumulativeCases=app.State.CumulativeCases;
              app.AverageCumulativeDeaths=app.State.CumulativeDeaths;
              app.AverageDailyDeaths=app.State.DailyDeaths;
              app.AverageDailyCases=app.State.DailyCases;
             else                
              app.AverageCumulativeCases=movmean(app.State.CumulativeCases,app.AverageOfDay);
              app.AverageCumulativeDeaths=movmean(app.State.CumulativeDeaths,app.AverageOfDay);
              app.AverageDailyDeaths=movmean(app.State.DailyDeaths,app.AverageOfDay);
              app.AverageDailyCases=movmean(app.State.DailyCases,app.AverageOfDay);
             end
           end
          end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load coviddata.mat covid_data;
            app.Global=covid_data;
            app.Defaultvalue(); 
            app.Generatecountryorstatedata();
            app.Plotgraph();
        end

        % Value changed function: CountryNameListBox
        function CountryNameValueChanged(app, event)
            %app.C=event.Value;
            app.ChangedCountry= app.CountryNameListBox.Value; 
            [~,app.IndexC]=ismember(app.ChangedCountry,app.Global(:,1));
            app.StateNamesListBox.Items = [ {'All'}, ...
                                               findobj([app.CountryList{:}], 'Name', app.ChangedCountry).ListOfStateNames ];
            app.ChangedState='All';
            app.Plotgraph();
        end

        % Value changed function: StateNamesListBox
        function StateNamesValueChanged(app, event)
            %app.S=event.Value;
            app.ChangedState= app.StateNamesListBox.Value;
            app.Plotgraph();
            
        end

        % Selection changed function: DataToPlotButtonGroup
        function DataToPlotButtonGroupSelectionChanged(app, event)
            app.ShowCases=app.CasesButton.Value||app.BothButton.Value;
            app.ShowBoth=app.BothButton.Value;
            app.ShowDeaths=app.DeathsButton.Value||app.BothButton.Value;          
            app.Plotgraph();
            
        end

        % Selection changed function: OptionForPlotButtonGroup
        function OptionForPlotButtonGroupSelectionChanged(app, event)
            app.ShowCumulative=app.CumulativeButton.Value;
            app.ShowDaily=app.DailyButton.Value;
            app.Plotgraph();
        end

        % Button pushed function: SetDefaultButton
        function SetDefaultButtonPushed(app, event)
            app.Defaultvalue();
            app.Plotgraph();
        end

        % Value changed function: AveragingwindowSlider
        function AveragingwindowSliderValueChanged(app, event)
            [~,minId] = min(abs(app.AveragingwindowSlider.Value - app.AveragingwindowSlider.MajorTicks(:)));
            % move the slider to that option
            app.AveragingwindowSlider.Value = app.AveragingwindowSlider.MajorTicks(minId);
            % override the selected value if you plan on using it within this function
            app.AverageOfDay = app.AveragingwindowSlider.MajorTicks(minId);
            % plot data
            app.Plotgraph();
            
        end

        % Value changed function: FromDatePicker
        function FromDatePickerValueChanged(app, event)
           app.StartDate = datenum(app.FromDatePicker.Value);
            if (app.StartDate >= app.EndDate)
                app.EndDate = min([ app.StartDate + 1,datenum(app.ToDatePicker.Limits(2)) ]);
                app.ToDatePicker.Value = datetime(app.EndDate, 'ConvertFrom', 'datenum');
                app.StartDate = app.EndDate - 1;
                app.FromDatePicker.Value = datetime(app.StartDate, 'ConvertFrom', 'datenum');
            end
            app.Plotgraph();
            
        end

        % Value changed function: ToDatePicker
        function ToDatePickerValueChanged(app, event)
            app.EndDate = datenum(app.ToDatePicker.Value);
            if (app.EndDate <= app.StartDate)
                app.StartDate = max([app.EndDate-1, datenum(app.FromDatePicker.Limits(1)) ]);
                app.FromDatePicker.Value = datetime(app.StartDate, 'ConvertFrom', 'datenum');
                app.ToDatePicker = app.StartDate + 1;
                app.ToDatePicker.Value = datetime(app.EndDate, 'ConvertFrom', 'datenum');
            end
            app.Plotgraph();
            
        end

        % Value changing function: SearchboxEditField
        function SearchboxEditFieldValueChanging(app, event)
            app.CountryorStatesearch = event.Value;
            if isempty(app.CountryorStatesearch)
                app.Defaultvalue();
                app.Plotgraph();
            else 
               strings=string(app.CountryorStatesearch);
               cntry=upper(app.CountryNamesList);
               strings=upper(strings);
               l=length(cntry);
               b=[];
               a=strlength(strings);        
               for i=1:l
                  if strlength(cntry{i})>=a
                     if isequal(cntry{i}(1:a),strings)
                        b=[b,i];
                     end 
                  else
                    continue;
                  end
               end 
               if ~isempty(b)
                option=app.CountryNamesList(b);
                app.CountryNameListBox.Items=option;
                app.CountryNameListBox.Value=option{1};
                app.CountryNameValueChanged();
               else
                  app.SearchboxEditField.BackgroundColor = 'red';
                  app.SearchboxEditField.FontColor = 'black';
                  app.CountryNameListBox.Items = "No country or region found.";
                  %app.SearchboxEditField.Value='No Country matched';                  
                  app.StateNamesListBox.Items={''};
                  cla(app.UIAxes, 'reset');
               end
            end
            app.StateNamesListBox
        end

        % Button pushed function: ClearSearchboxButton
        function ClearSearchboxButtonPushed(app, event)
           app.SearchboxEditField.Value ='';
           app.SearchboxEditField.BackgroundColor = 'White';
            app.Defaultvalue();
            app.Plotgraph();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CountryNameListBoxLabel = uilabel(app.UIFigure);
            app.CountryNameListBoxLabel.Text = 'Country Name';
            app.CountryNameListBox = uilistbox(app.UIFigure);
            app.CountryNameListBox.ValueChangedFcn = createCallbackFcn(app, @CountryNameValueChanged, true);
            app.StateNamesListBoxLabel = uilabel(app.UIFigure);
            app.StateNamesListBoxLabel.Text = 'State Names';
            app.StateNamesListBox = uilistbox(app.UIFigure);
            app.StateNamesListBox.ValueChangedFcn = createCallbackFcn(app, @StateNamesValueChanged, true);
            app.OptionForPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionForPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionForPlotButtonGroupSelectionChanged, true);
            app.OptionForPlotButtonGroup.Title = 'Option For Plot ';
            app.CumulativeButton = uiradiobutton(app.OptionForPlotButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionForPlotButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.DataToPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DataToPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataToPlotButtonGroupSelectionChanged, true);
            app.DataToPlotButtonGroup.Title = 'Data To Plot';
            app.CasesButton = uiradiobutton(app.DataToPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DataToPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DataToPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.AveragingwindowSliderLabel = uilabel(app.UIFigure);
            app.AveragingwindowSliderLabel.Text = 'Averaging window';
            app.AveragingwindowSlider = uislider(app.UIFigure);
            app.AveragingwindowSlider.Limits = [1 15];
            app.AveragingwindowSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AveragingwindowSlider.ValueChangedFcn = createCallbackFcn(app, @AveragingwindowSliderValueChanged, true);
            app.AveragingwindowSlider.Value = 1;
            app.SetDefaultButton = uibutton(app.UIFigure, 'push');
            app.SetDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetDefaultButtonPushed, true);
            app.SetDefaultButton.Text = 'Set Default';
            app.StartDateLabel = uilabel(app.UIFigure);
            app.StartDateLabel.Text = 'EndDate';
            app.ToDatePicker = uidatepicker(app.UIFigure);
            app.ToDatePicker.DisplayFormat = 'dd/MMM/uuuu';
            app.ToDatePicker.ValueChangedFcn = createCallbackFcn(app, @ToDatePickerValueChanged, true);
            app.ToDatePicker.Value = datetime([2023 1 1]);
            app.EndDateLabel = uilabel(app.UIFigure);
            app.EndDateLabel.Text = 'StartDate';
            app.FromDatePicker = uidatepicker(app.UIFigure);
            app.FromDatePicker.DisplayFormat = 'dd/MMM/uuuu';
            app.FromDatePicker.ValueChangedFcn = createCallbackFcn(app, @FromDatePickerValueChanged, true);
            app.FromDatePicker.Value = datetime([2020 1 1]);
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://coronavirus.jhu.edu/map.html';
            app.Hyperlink.Text = 'Source Of Data:- Coronavirus Resource Center at Johns Hopkins University';
            app.SearchboxEditFieldLabel = uilabel(app.UIFigure);
            app.SearchboxEditFieldLabel.Text = 'Searchbox';
            app.SearchboxEditField = uieditfield(app.UIFigure, 'text');
            app.SearchboxEditField.ValueChangingFcn = createCallbackFcn(app, @SearchboxEditFieldValueChanging, true);
            app.ClearSearchboxButton = uibutton(app.UIFigure, 'push');
            app.ClearSearchboxButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSearchboxButtonPushed, true);
            app.ClearSearchboxButton.Text = 'Clear Searchbox';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidDataVisualisation_Dashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end